classdef PcctTool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        PCCTToolkitV01zhouenzeLabel  matlab.ui.control.Label
        TabGroup                     matlab.ui.container.TabGroup
        Tab_4                        matlab.ui.container.Tab
        Spinner_2                    matlab.ui.control.Spinner
        Spinner                      matlab.ui.control.Spinner
        Button_11                    matlab.ui.control.Button
        Button_10                    matlab.ui.control.Button
        MaskEditField_2              matlab.ui.control.NumericEditField
        MaskEditField_2Label         matlab.ui.control.Label
        Button_9                     matlab.ui.control.Button
        TotalTextArea_2              matlab.ui.control.TextArea
        TotalTextArea_2Label         matlab.ui.control.Label
        HighTextArea_2               matlab.ui.control.TextArea
        HighTextArea_2Label          matlab.ui.control.Label
        LowTextArea_4                matlab.ui.control.TextArea
        LowTextArea_4Label           matlab.ui.control.Label
        water1000Label_3             matlab.ui.control.Label
        TotalTextArea                matlab.ui.control.TextArea
        TotalTextAreaLabel           matlab.ui.control.Label
        HighTextArea                 matlab.ui.control.TextArea
        HighTextAreaLabel            matlab.ui.control.Label
        mgcm3EditField_2             matlab.ui.control.NumericEditField
        mgcm3Label_2                 matlab.ui.control.Label
        mgcm3EditField               matlab.ui.control.NumericEditField
        mgcm3EditFieldLabel          matlab.ui.control.Label
        MaskEditField                matlab.ui.control.NumericEditField
        Label_13                     matlab.ui.control.Label
        EditField_2                  matlab.ui.control.NumericEditField
        Label_12                     matlab.ui.control.Label
        EditField                    matlab.ui.control.NumericEditField
        Label_11                     matlab.ui.control.Label
        LowTextArea                  matlab.ui.control.TextArea
        LowTextAreaLabel             matlab.ui.control.Label
        TextArea_2                   matlab.ui.control.TextArea
        Label_3                      matlab.ui.control.Label
        TotalLamp                    matlab.ui.control.Lamp
        TotalLampLabel               matlab.ui.control.Label
        HighLamp                     matlab.ui.control.Lamp
        HighLampLabel                matlab.ui.control.Label
        LowLamp                      matlab.ui.control.Lamp
        LowLampLabel                 matlab.ui.control.Label
        TextArea                     matlab.ui.control.TextArea
        Label_2                      matlab.ui.control.Label
        SliceSlider                  matlab.ui.control.RangeSlider
        SliceSliderLabel             matlab.ui.control.Label
        water1000Label_2             matlab.ui.control.Label
        Button_3                     matlab.ui.control.Button
        Button_2                     matlab.ui.control.Button
        Button                       matlab.ui.control.Button
        LoadDicomFilesButton         matlab.ui.control.Button
        UITable                      matlab.ui.control.Table
        CaliShow                     matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
        Tab_2                        matlab.ui.container.Tab
        DropDown_2                   matlab.ui.control.DropDown
        Label_15                     matlab.ui.control.Label
        DropDown                     matlab.ui.control.DropDown
        Label_14                     matlab.ui.control.Label
        EditField_8                  matlab.ui.control.NumericEditField
        EditField_8Label             matlab.ui.control.Label
        EditField_7                  matlab.ui.control.NumericEditField
        EditField_7Label             matlab.ui.control.Label
        EditField_6                  matlab.ui.control.NumericEditField
        EditField_6Label             matlab.ui.control.Label
        EditField_5                  matlab.ui.control.NumericEditField
        EditField_5Label             matlab.ui.control.Label
        EditField_4                  matlab.ui.control.NumericEditField
        EditField_4Label             matlab.ui.control.Label
        EditField_3                  matlab.ui.control.NumericEditField
        EditField_3Label             matlab.ui.control.Label
        Button_8                     matlab.ui.control.StateButton
        Button_6                     matlab.ui.control.Button
        MD_show_log                  matlab.ui.control.TextArea
        Label_10                     matlab.ui.control.Label
        Button_5                     matlab.ui.control.Button
        addMDtable                   matlab.ui.control.Button
        UITable_MD                   matlab.ui.control.Table
        HighDicomFilesButton         matlab.ui.control.Button
        SliceSlider_2                matlab.ui.control.Slider
        Label                        matlab.ui.control.Label
        LowDicomFilesButton          matlab.ui.control.Button
        MDShow_4                     matlab.ui.control.UIAxes
        MDShow_3                     matlab.ui.control.UIAxes
        MDShow_2                     matlab.ui.control.UIAxes
        MDShow_1                     matlab.ui.control.UIAxes
        Tab_3                        matlab.ui.container.Tab
        TODOLabel                    matlab.ui.control.Label
        Tab_5                        matlab.ui.container.Tab
        TODOLabel_2                  matlab.ui.control.Label
        Tab_7                        matlab.ui.container.Tab
        TODOLabel_3                  matlab.ui.control.Label
        Tab_6                        matlab.ui.container.Tab
        TODOLabel_4                  matlab.ui.control.Label
    end

    
    properties (Access = public)
        is_UIH_data = 0 % 是不是UIH生成的原始数据
        dicom_dir = []; %用于标定dicom文件夹路径
        dicom_name_list = []; %储存dicom文件名
        is_cali_all_e = 1; %是否一次性校准所有能量图像
        cali_dicom_dir_low = [];
        cali_dicom_name_list_low = [];
        cali_dicom_dir_high = [];
        cali_dicom_name_list_high = [];
        cali_dicom_dir_total = [];
        cali_dicom_name_list_total = [];
        cali_is_first_load = 1;
        cali_WW_WL = [1000 400]; %标定所用窗宽窗位
        cali_slice_index = [1 1]; %选定标定区间
        cali_slice_show_index = 1; %当前标定显示slice编号
        cali_image = []; %当前标定图像
        cali_slice_num = []; % 标定文件slice数量
        cali_Energy = 'Low'; % 标定能量，1：Low，2：High，3：Total
        cali_Energy_index = 1;
        cali_CenterPoint1 = [];
        cali_CenterPoint2 = [];
        cali_circleR1 = 100;
        cali_circleR2 = 100;
        cali_circleHandle1 = [];
        cali_circleHandle2 = [];
        cali_is_first_slice = 1; %当前显示是起始slice or end slice
        cali_baseM1 = [];
        cali_baseM2 = [];
        cali_baseM1_density = 1000;
        cali_baseM2_density = 1000;
        cali_table = [];
        cali_results = [];
        cali_result_save_path = [];
        cali_run_enable = 0;

        MD_dicom_dir_low = [];
        MD_dicom_name_list_low = [];
        MD_dicom_dir_high = [];
        MD_dicom_name_list_high = [];
        MD_image_low = [];
        MD_image_high = [];
        MD_image_M1 = [];
        MD_image_M2 = [];
        MD_slice_num = [];
        MD_slice_show_index = 1;
        MD_WW_WL_HL = [1000 400];
        MD_WW_WL_M1 = [1000 500];
        MD_WW_WL_M2 = [1000 500];
        MD_baseMaterial = ["Water"];
        MD_baseMaterial_table = [1000,1000];
        MD_baseMaterial_table_path = [];
        MD_choose_M1 = 1;
        MD_choose_M2 = 1;
        MD_choose_table = [1000,1000;1000,1000];
        MD_save_path = [];
        MD_run_index = 1;
        MD_run_enable = 1;
        MD_dicom_info = [];
        MD_is_show_log = 1; %是否打印进度
    end
    
    methods (Access = private)
        
        function show_cali_dicom(app)
            imgHandle = imagesc(app.CaliShow,app.cali_image);
            colormap(app.CaliShow,"gray"); % 设置灰度显示
            clim(app.CaliShow, [app.cali_WW_WL(2)-app.cali_WW_WL(1)/2,app.cali_WW_WL(2)+app.cali_WW_WL(1)/2]);
            % axis(app.CaliShow, 'image');  % 保持等比例
            % axis(app.CaliShow, 'tight');
            imgHandle.HitTest = 'off';
            % cali_E_light(app)
        end
        function show_MD_dicom(app,I_type)
            if I_type(1) & ~isempty(app.MD_image_low)
                imagesc(app.MDShow_1,app.MD_image_low);
                colormap(app.MDShow_1,"gray"); % 设置灰度显示
                clim(app.MDShow_1, [app.MD_WW_WL_HL(2)-app.MD_WW_WL_HL(1)/2,app.MD_WW_WL_HL(2)+app.MD_WW_WL_HL(1)/2]);
                axis(app.MDShow_1, 'image');  % 保持等比例
                axis(app.MDShow_1, 'tight');
            end
            if I_type(2) & ~isempty(app.MD_image_high)
                imagesc(app.MDShow_2,app.MD_image_high);
                colormap(app.MDShow_2,"gray"); % 设置灰度显示
                clim(app.MDShow_2, [app.MD_WW_WL_HL(2)-app.MD_WW_WL_HL(1)/2,app.MD_WW_WL_HL(2)+app.MD_WW_WL_HL(1)/2]);
                axis(app.MDShow_2, 'image');  % 保持等比例
                axis(app.MDShow_2, 'tight');
            end
            if I_type(3) & ~isempty(app.MD_image_M1)
                imagesc(app.MDShow_3,app.MD_image_M1);
                colormap(app.MDShow_3,"gray"); % 设置灰度显示
                clim(app.MDShow_3, [app.MD_WW_WL_M1(2)-app.MD_WW_WL_M1(1)/2,app.MD_WW_WL_M1(2)+app.MD_WW_WL_M1(1)/2]);
                axis(app.MDShow_3, 'image');  % 保持等比例
                axis(app.MDShow_3, 'tight');
            end
            if I_type(4) & ~isempty(app.MD_image_M2)
                imagesc(app.MDShow_4,app.MD_image_M2);
                colormap(app.MDShow_4,"gray"); % 设置灰度显示
                clim(app.MDShow_4, [app.MD_WW_WL_M2(2)-app.MD_WW_WL_M2(1)/2,app.MD_WW_WL_M2(2)+app.MD_WW_WL_M2(1)/2]);
                axis(app.MDShow_4, 'image');  % 保持等比例
                axis(app.MDShow_4, 'tight');
            end
        end
        function show_circle(app)
            if app.cali_is_first_slice
                if ~isempty(app.cali_circleHandle1)
                        delete(app.cali_circleHandle1);
                end
                app.cali_circleHandle1 = viscircles(app.CaliShow, app.cali_CenterPoint1, app.cali_circleR1, 'EdgeColor', 'r');
            else
                if ~isempty(app.cali_circleHandle2)
                        delete(app.cali_circleHandle2);
                end
                app.cali_circleHandle2 = viscircles(app.CaliShow, app.cali_CenterPoint2, app.cali_circleR2, 'EdgeColor', 'r');
            end
        end
        
        function read_dicom(app)
            info = dicominfo([app.dicom_dir,'\',app.dicom_name_list(app.cali_slice_show_index).name]);
            img = double(dicomread(info));
            app.cali_image = img.*info.RescaleSlope + info.RescaleIntercept;
            app.cali_Energy = info.Private_0063_5017;
        end
        function MD_read_dicom(app,I_type)
            if I_type(1)&~isempty(app.MD_dicom_name_list_low)
                info = dicominfo([app.MD_dicom_dir_low,'\',app.MD_dicom_name_list_low(app.MD_slice_show_index).name]);
                img = double(dicomread(info));
                app.MD_image_low = img.*info.RescaleSlope + info.RescaleIntercept;
            end
            if I_type(2)&~isempty(app.MD_dicom_name_list_high)
                info = dicominfo([app.MD_dicom_dir_high,'\',app.MD_dicom_name_list_high(app.MD_slice_show_index).name]);
                img = double(dicomread(info));
                app.MD_image_high = img.*info.RescaleSlope + info.RescaleIntercept;
            end
            app.MD_dicom_info = info;
        end
        function MD_image_and_save(app)
            for i = app.MD_run_index:1:app.MD_slice_num
                if app.MD_run_enable
                    app.MD_run_index = i;
                    app.MD_slice_show_index = i;
                    MD_read_dicom(app,[1 1]);
                    temp_low = app.MD_image_low + 1000;
                    temp_high = app.MD_image_high + 1000;
                    img_size = size(temp_low);
                    img = [temp_low(:)';temp_high(:)'];
                    MD = app.MD_choose_table;
                    temp_DM_images = MD\img * 1000;% g/cm^3 to mg/cm^3
                    MaterialPair.M1 = reshape(temp_DM_images(1,:),img_size);
                    MaterialPair.M2 = reshape(temp_DM_images(2,:),img_size);
                    app.MD_image_M1 = MaterialPair.M1;
                    app.MD_image_M2 = MaterialPair.M2;
                    if app.MD_is_show_log
                        show_MD_dicom(app,[1 1 1 1]);
                        drawnow;
                        app.SliceSlider_2.Value = app.MD_slice_show_index;
                    end
                    %保存结果
                    app.MD_show_log.Value = [app.MD_save_path,'\',num2str(app.MD_dicom_info.InstanceNumber,'%05d'),'.dcm'];
                    MaterialNames = app.MD_baseMaterial([app.MD_choose_M1,app.MD_choose_M2]);
                    MaterialPairDicomSave(MaterialPair, MaterialNames, app.MD_save_path, app.MD_dicom_info);
                else
                    return
                end
            end
            app.MD_run_index = 1; %从头开始
        end
        function cali_E_light(app)
            if app.is_cali_all_e
                if strcmp(app.cali_Energy,'Low')
                    app.cali_dicom_dir_low = app.dicom_dir;
                    app.cali_dicom_name_list_low = app.dicom_name_list;
                    app.cali_Energy_index = 1;
                    app.LowLamp.Color = [0.00,1.00,0.00];
                elseif strcmp(app.cali_Energy,'High')
                    app.cali_dicom_dir_high = app.dicom_dir;
                    app.cali_dicom_name_list_high = app.dicom_name_list;
                    app.cali_Energy_index = 2;
                    app.HighLamp.Color = [0.00,1.00,0.00];
                elseif strcmp(app.cali_Energy,'Total')
                    app.cali_dicom_dir_total = app.dicom_dir;
                    app.cali_dicom_name_list_total = app.dicom_name_list;
                    app.cali_Energy_index = 3;
                    app.TotalLamp.Color = [0.00,1.00,0.00];
                else
                    app.LowLamp.Color = [0.80,0.80,0.80];
                    app.HighLamp.Color = [0.80,0.80,0.80];
                    app.TotalLamp.Color = [0.80,0.80,0.80];
                end
            else
                if strcmp(app.cali_Energy,'Low')
                    app.cali_Energy_index = 1;
                    app.LowLamp.Color = [0.00,1.00,0.00];
                    app.HighLamp.Color = [0.80,0.80,0.80];
                    app.TotalLamp.Color = [0.80,0.80,0.80];
                elseif strcmp(app.cali_Energy,'High')
                    app.cali_Energy_index = 2;
                    app.LowLamp.Color = [0.80,0.80,0.80];
                    app.HighLamp.Color = [0.00,1.00,0.00];
                    app.TotalLamp.Color = [0.80,0.80,0.80];
                elseif strcmp(app.cali_Energy,'Total')
                    app.cali_Energy_index = 3;
                    app.LowLamp.Color = [0.80,0.80,0.80];
                    app.HighLamp.Color = [0.80,0.80,0.80];
                    app.TotalLamp.Color = [0.00,1.00,0.00];
                else
                    app.LowLamp.Color = [0.80,0.80,0.80];
                    app.HighLamp.Color = [0.80,0.80,0.80];
                    app.TotalLamp.Color = [0.80,0.80,0.80];
                end
            end
        end
        function read_dicom_list(app)
            slice_num = app.cali_slice_index(2) - app.cali_slice_index(1) + 1;
            CT_value = 0;
            CT_value_low = 0;
            CT_value_high = 0;
            CT_value_total = 0;
            for i = 1:1:slice_num
                app.cali_slice_show_index = app.cali_slice_index(1)+i-1;
                center = app.cali_CenterPoint1 + (app.cali_CenterPoint2-app.cali_CenterPoint1)*(i-1)/(slice_num-1);
                r_value = app.cali_circleR1 + (app.cali_circleR2-app.cali_circleR1)*(i-1)/(slice_num-1);
                if app.is_cali_all_e
                    if length(app.cali_dicom_name_list_low)>0
                        info = dicominfo([app.cali_dicom_dir_low,'\',app.cali_dicom_name_list_low(app.cali_slice_show_index).name]);
                        img_low = double(dicomread(info));
                        img_low = img_low.*info.RescaleSlope + info.RescaleIntercept;
                        temp_CT_value = calcu_roi(img_low,center,r_value);
                        CT_value_low = CT_value_low*(i-1)/i+temp_CT_value(1)/i;
                    end
                    if length(app.cali_dicom_name_list_high)>0
                        info = dicominfo([app.cali_dicom_dir_high,'\',app.cali_dicom_name_list_high(app.cali_slice_show_index).name]);
                        img_high = double(dicomread(info));
                        img_high = img_high.*info.RescaleSlope + info.RescaleIntercept;
                        temp_CT_value = calcu_roi(img_high,center,r_value);
                        CT_value_high = CT_value_high*(i-1)/i+temp_CT_value(1)/i;
                    end
                    if length(app.cali_dicom_name_list_total)>0
                        info = dicominfo([app.cali_dicom_dir_total,'\',app.cali_dicom_name_list_total(app.cali_slice_show_index).name]);
                        img_total = double(dicomread(info));
                        img_total = img_total.*info.RescaleSlope + info.RescaleIntercept;
                        temp_CT_value = calcu_roi(img_total,center,r_value);
                        CT_value_total = CT_value_total*(i-1)/i+temp_CT_value(1)/i;
                    end
                    switch app.cali_Energy_index
                        case 1
                            app.cali_image = img_low;
                        case 2
                            app.cali_image = img_high;
                        case 3
                            app.cali_image = img_total;
                    end
                else
                    info = dicominfo([app.dicom_dir,'\',app.dicom_name_list(app.cali_slice_show_index).name]);
                    img = double(dicomread(info));
                    app.cali_image = img.*info.RescaleSlope + info.RescaleIntercept;
                    % center = app.cali_CenterPoint1 + (app.cali_CenterPoint2-app.cali_CenterPoint1)*(i-1)/(slice_num-1);
                    % r_value = app.cali_circleR1 + (app.cali_circleR2-app.cali_circleR1)*(i-1)/(slice_num-1);
            %         ihandle = imagesc(app.CaliShow,app.cali_image,"BusyAction","queue");
            %         ihandle.HitTest = "off";
            %         delete(app.cali_circleHandle1);
            %         delete(app.cali_circleHandle2);
            %         app.cali_circleHandle2 = viscircles(app.CaliShow, center, r_value, 'EdgeColor', 'r');
            %         % ---显示当前进度
            %         hold(app.CaliShow, 'on');
            %         % 获取当前 UIAxes 显示的坐标范围
            %         xLimits = xlim(app.CaliShow);
            %         yLimits = ylim(app.CaliShow);
            %         % 计算右下角位置 (留一点边距)
            %         x_pos = xLimits(2) - 20;  % 右边留 10 个单位
            %         y_pos = yLimits(2) - 20;  % 下方留 10 个单位
            %         text(app.CaliShow, x_pos, y_pos, ['Slice:',num2str(app.cali_slice_show_index)], 'Color', 'w', 'FontSize', 14, ...
            % 'HorizontalAlignment', 'right', 'VerticalAlignment', 'bottom', 'FontWeight', 'bold');
            %         hold(app.CaliShow, 'off');
            %         drawnow;
                    temp_CT_value = calcu_roi(app.cali_image,center,r_value);
                    CT_value = CT_value*(i-1)/i+temp_CT_value(1)/i;
                end
                ihandle = imagesc(app.CaliShow,app.cali_image,"BusyAction","queue");
                ihandle.HitTest = "off";
                delete(app.cali_circleHandle1);
                delete(app.cali_circleHandle2);
                app.cali_circleHandle2 = viscircles(app.CaliShow, center, r_value, 'EdgeColor', 'r');
                % ---显示当前进度
                hold(app.CaliShow, 'on');
                % 获取当前 UIAxes 显示的坐标范围
                xLimits = xlim(app.CaliShow);
                yLimits = ylim(app.CaliShow);
                % 计算右下角位置 (留一点边距)
                x_pos = xLimits(2) - 20;  % 右边留 10 个单位
                y_pos = yLimits(2) - 20;  % 下方留 10 个单位
                text(app.CaliShow, x_pos, y_pos, ['Slice:',num2str(app.cali_slice_show_index)], 'Color',[0.8 0.8 0.8], 'FontSize', 14, ...
        'HorizontalAlignment', 'right', 'VerticalAlignment', 'bottom', 'FontWeight', 'bold');
                hold(app.CaliShow, 'off');
                drawnow;

                if ~app.cali_run_enable
                    break
                end
            end
            app.cali_run_enable = 0;
            app.cali_is_first_slice = 0;
            if app.is_cali_all_e
                if isempty(app.cali_table)
                    init_item = nan(1,5);
                    init_item(1,1:2) = [app.cali_baseM1_density,app.cali_baseM2_density];
                    init_item(3) = CT_value_low;
                    init_item(4) = CT_value_high;
                    init_item(5) = CT_value_total;
                    app.cali_table = init_item;
                else
                    is_new_item = 0;
                    for j = 1:size(app.cali_table,1)
                        if app.cali_table(j,1)==app.cali_baseM1_density&&app.cali_table(j,2)==app.cali_baseM2_density
                            is_new_item = 1;
                            break
                        end
                    end
                    if is_new_item
                        app.cali_table(j,3) = CT_value_low;
                        app.cali_table(j,4) = CT_value_high;
                        app.cali_table(j,5) = CT_value_total;
                    else
                        init_item = nan(1,5);
                        init_item(1,1:2) = [app.cali_baseM1_density,app.cali_baseM2_density];
                        init_item(3) = CT_value_low;
                        init_item(4) = CT_value_high;
                        init_item(5) = CT_value_total;
                        app.cali_table = [app.cali_table;init_item];
                    end
                end
            else
                if isempty(app.cali_table)
                    init_item = nan(1,5);
                    init_item(1,1:2) = [app.cali_baseM1_density,app.cali_baseM2_density];
                    init_item(app.cali_Energy_index+2) = CT_value;
                    app.cali_table = init_item;
                else
                    is_new_item = 0;
                    for j = 1:size(app.cali_table,1)
                        if app.cali_table(j,1)==app.cali_baseM1_density&&app.cali_table(j,2)==app.cali_baseM2_density
                            is_new_item = 1;
                            break
                        end
                    end
                    if is_new_item
                        app.cali_table(j,app.cali_Energy_index+2) = CT_value;
                    else
                        init_item = nan(1,5);
                        init_item(1,1:2) = [app.cali_baseM1_density,app.cali_baseM2_density];
                        init_item(app.cali_Energy_index+2) = CT_value;
                        app.cali_table = [app.cali_table;init_item];
                    end
                end
            end
            app.UITable.Data = table(app.cali_table(:,1),app.cali_table(:,2),app.cali_table(:,3),app.cali_table(:,4),app.cali_table(:,5));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDicomFilesButton
        function LoadDicomFilesButtonPushed(app, event)
            if ~isdir(app.dicom_dir)
                temp_dir = uigetdir(app.dicom_dir,'选择待标定能量文件夹');  % 创建标准的对话框并通过交互式操作取得文件夹名
            else
                temp_dir = uigetdir(fileparts(app.dicom_dir),'选择待标定能量文件夹');  % 创建标准的对话框并通过交互式操作取得文件夹名
            end
            if temp_dir~=0
                app.dicom_dir = temp_dir;
            else
                return
            end
            file_list = dir([app.dicom_dir '\*.dcm']);
            if length(file_list)==0
                errordlg('选择路径有误：不存在dicom文件！', '错误');
                return
            end
            name = file_list(1).name;
            name = split(name,'.');
            if length(name)>2 %由于UIH保存的dicom文件名有其特定规律，并不能按顺序读取slice，需要重新排序
                app.is_UIH_data = 1;
                name_list = zeros(length(file_list),1);
                for i = 1:length(file_list)
                    name = file_list(i).name;
                    name = split(name,'.');
                    name = str2num(cell2mat(name(6)))*1000000+str2num(cell2mat(name(9)));
                    name_list(i) = name;
                end
                [~,index] = sort(name_list);
                app.dicom_name_list = file_list(index);
            else
                app.dicom_name_list = file_list;
            end
            % 更改图标
            app.cali_slice_num = length(file_list);
            app.SliceSlider.Limits = [1 app.cali_slice_num];
            app.Spinner_2.Limits = [1 app.cali_slice_num];
            app.Spinner_2.Value = app.cali_slice_num;
            app.Spinner.Limits = [1 app.cali_slice_num];
            read_dicom(app);
            cali_E_light(app);
            show_cali_dicom(app);
            if app.cali_is_first_load
                app.SliceSlider.Value = [1 app.cali_slice_num];
                app.cali_slice_index = [1 app.cali_slice_num];
                app.cali_is_first_load = 0;
            else
                show_circle(app);
            end
            

        end

        % Value changed function: SliceSlider
        function SliceSliderValueChanged(app, event)
            value = app.SliceSlider.Value;
            is_changed = ~(value==app.cali_slice_index);
            if sum(is_changed)
                temp = value(is_changed);
                app.cali_slice_show_index = temp(1);
                read_dicom(app);
                show_cali_dicom(app);
            end
            if is_changed(1)
                app.cali_is_first_slice = 1;
                app.MaskEditField.Value = app.cali_circleR1;
            else
                app.cali_is_first_slice = 0;
                app.MaskEditField.Value = app.cali_circleR2;
            end
            % show_cali_dicom(app);
            show_circle(app);
            app.cali_slice_index(1) = fix(value(1));
            app.cali_slice_index(2) = fix(value(2));
            app.Spinner.Value = fix(value(1));
            app.Spinner_2.Value = fix(value(2));
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            app.cali_WW_WL(1) = app.EditField.Value;
            show_cali_dicom(app);
            show_circle(app);
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            app.cali_WW_WL(2) = app.EditField_2.Value;
            show_cali_dicom(app);
            show_circle(app);
        end

        % Button down function: CaliShow
        function CaliShowButtonDown(app, event)
            if app.cali_is_first_slice
                app.cali_CenterPoint1 = app.CaliShow.CurrentPoint(1,1:2);
            else
                app.cali_CenterPoint2 = app.CaliShow.CurrentPoint(1,1:2);
            end
            show_circle(app)
        end

        % Value changed function: MaskEditField
        function MaskEditFieldValueChanged(app, event)
            value = app.MaskEditField.Value;
            % if app.cali_is_first_slice
            %     app.cali_circleR1 = value;
            % else
            %     app.cali_circleR2 = value;
            % end
            % show_circle(app)
            app.cali_circleR1 = value;
            show_circle(app)
        end

        % Value changed function: MaskEditField_2
        function MaskEditField_2ValueChanged(app, event)
            value = app.MaskEditField_2.Value;
            app.cali_circleR2 = value;
            show_circle(app)
        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
            value = app.TextArea.Value;
            app.cali_baseM1 = value;
        end

        % Value changed function: TextArea_2
        function TextArea_2ValueChanged(app, event)
            value = app.TextArea_2.Value;
            app.cali_baseM2 = value;
        end

        % Value changed function: mgcm3EditField
        function mgcm3EditFieldValueChanged(app, event)
            value = app.mgcm3EditField.Value;
            app.cali_baseM1_density = value;
        end

        % Value changed function: mgcm3EditField_2
        function mgcm3EditField_2ValueChanged(app, event)
            value = app.mgcm3EditField_2.Value;
            app.cali_baseM2_density = value;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            if (isempty(app.cali_circleHandle1))|(isempty(app.cali_circleHandle2))
                errordlg('请先选择好起始和终止掩膜Mask！', '错误');
                return
            end
            if app.cali_run_enable
                app.cali_run_enable = 0;
            else
                app.cali_run_enable = 1;
                read_dicom_list(app);
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)

            X(:,1) = app.cali_table(:,1)/1000; %mg/cm^3 to g/cm^3
            X(:,2) = app.cali_table(:,2)/1000; %mg/cm^3 to g/cm^3
            z_low = app.cali_table(:,3)+1000;
            z_high = app.cali_table(:,4)+1000;
            z_total = app.cali_table(:,5)+1000;
            low_index = ~isnan(z_low);
            app.cali_results(:,1) = regress(z_low(low_index),X(low_index,:));
            high_index = ~isnan(z_high);
            app.cali_results(:,2) = regress(z_high(high_index),X(high_index,:));
            total_index = ~isnan(z_total);
            app.cali_results(:,3) = regress(z_total(total_index),X(total_index,:));
            plot(app.UIAxes,app.cali_table(:,1),app.cali_table(:,3:5),"Marker","o");
            hold(app.UIAxes,"on")
            predict_low = app.cali_results(1,1).*X(:,1)+app.cali_results(2,1).*X(:,2)-1000;
            predict_high = app.cali_results(1,2).*X(:,1)+app.cali_results(2,2).*X(:,2)-1000;
            predict_total = app.cali_results(1,3).*X(:,1)+app.cali_results(2,3).*X(:,2)-1000;
            plot(app.UIAxes,app.cali_table(:,1),[predict_low,predict_high,predict_total],"LineStyle","--");
            hold(app.UIAxes,"off")
            legend(app.UIAxes,'Low-real','High-real','Total-real','Low-fit','High-fit','Total-fit');
            app.LowTextArea.Value = num2str(fix(app.cali_results(1,1)));
            app.HighTextArea.Value = num2str(fix(app.cali_results(1,2)));
            app.TotalTextArea.Value = num2str(fix(app.cali_results(1,3)));
            app.LowTextArea_4.Value = num2str(fix(app.cali_results(2,1)));
            app.HighTextArea_2.Value = num2str(fix(app.cali_results(2,2)));
            app.TotalTextArea_2.Value = num2str(fix(app.cali_results(2,3)));
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            selection = app.UITable.Selection;
            delete_row = unique(selection(selection(:,2)==1|selection(:,2)==2,1));
            if ~isempty(delete_row)
                app.cali_table(delete_row,:) = [];
            else
                app.cali_table(selection(1),selection(2)) = nan;
            end
            app.UITable.Data = table(app.cali_table(:,1),app.cali_table(:,2),app.cali_table(:,3),app.cali_table(:,4),app.cali_table(:,5));
        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)
            % selection = app.UITable.Selection;
            % app.cali_table = app.UITable.Data.Variables;
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            app.cali_table(indices) = newData;
            app.cali_table = app.UITable.Data.Variables;
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)

            if (isempty(app.cali_baseM1)||(iscell(app.cali_baseM1)&&isempty(app.cali_baseM1{1})))|(isempty(app.cali_baseM2)||(iscell(app.cali_baseM2)&&isempty(app.cali_baseM2{1})))
                errordlg('请先填写基物质名称！', '错误');
            else
                if isdir(app.cali_result_save_path)
                    [filename, pathname] = uiputfile(fullfile(app.cali_result_save_path, [char(app.cali_baseM1),'_',char(app.cali_baseM2),'_CaliData.mat']), '保存能谱标定结果');
                else
                    [filename, pathname] = uiputfile(fullfile(fileparts(app.dicom_dir), [char(app.cali_baseM1),'_',char(app.cali_baseM2),'_CaliData.mat']), '保存能谱标定结果');
                end
                if isequal(filename, 0) || isequal(pathname, 0)
                    % disp('用户取消了保存操作');
                else
                    app.cali_result_save_path = pathname;
                    fullpath = fullfile(pathname, filename);
                    cali_data = app.cali_results;
                    cali_M = [app.cali_baseM1;app.cali_baseM2];
                    save(fullpath, 'cali_data','cali_M'); % 保存数据
                end
            end
            
        end

        % Button pushed function: LowDicomFilesButton
        function LowDicomFilesButtonPushed(app, event)
            if ~isdir(app.MD_dicom_dir_low)
                temp_dir = uigetdir(app.MD_dicom_dir_low,'选择待分解低能图像文件夹');  % 创建标准的对话框并通过交互式操作取得文件夹名
            else
                temp_dir = uigetdir(fileparts(app.MD_dicom_dir_low),'选择待分解低能图像文件夹');  % 创建标准的对话框并通过交互式操作取得文件夹名
            end
            if temp_dir~=0
                app.MD_dicom_dir_low = temp_dir;
            else
                return
            end
            file_list = dir([app.MD_dicom_dir_low '\*.dcm']);
            if length(file_list)==0
                errordlg('选择路径有误：不存在dicom文件！', '错误');
                return
            end
            name = file_list(1).name;
            name = split(name,'.');
            if length(name)>2 %由于UIH保存的dicom文件名有其特定规律，并不能按顺序读取slice，需要重新排序
                app.is_UIH_data = 1;
                name_list = zeros(length(file_list),1);
                for i = 1:length(file_list)
                    name = file_list(i).name;
                    name = split(name,'.');
                    name = str2num(cell2mat(name(6)))*1000000+str2num(cell2mat(name(9)));
                    name_list(i) = name;
                end
                [~,index] = sort(name_list);
                app.MD_dicom_name_list_low = file_list(index);
            else
                app.MD_dicom_name_list_low = file_list;
            end
            % 更改图标
            app.MD_slice_num = length(file_list);
            app.SliceSlider_2.Limits = [1 app.MD_slice_num];
            MD_read_dicom(app,[1 0]);
            show_MD_dicom(app,[1 0 0 0]);
        end

        % Button pushed function: HighDicomFilesButton
        function HighDicomFilesButtonPushed(app, event)
            if ~isdir(app.MD_dicom_dir_high)
                temp_dir = uigetdir(app.MD_dicom_dir_high,'选择待分解高能图像文件夹');  % 创建标准的对话框并通过交互式操作取得文件夹名
            else
                temp_dir = uigetdir(fileparts(app.MD_dicom_dir_high),'选择待分解高能图像文件夹');  % 创建标准的对话框并通过交互式操作取得文件夹名
            end
            if temp_dir~=0
                app.MD_dicom_dir_high = temp_dir;
            else
                return
            end
            file_list = dir([app.MD_dicom_dir_high '\*.dcm']);
            if length(file_list)==0
                errordlg('选择路径有误：不存在dicom文件！', '错误');
                return
            end
            name = file_list(1).name;
            name = split(name,'.');
            if length(name)>2 %由于UIH保存的dicom文件名有其特定规律，并不能按顺序读取slice，需要重新排序
                app.is_UIH_data = 1;
                name_list = zeros(length(file_list),1);
                for i = 1:length(file_list)
                    name = file_list(i).name;
                    name = split(name,'.');
                    name = str2num(cell2mat(name(6)))*1000000+str2num(cell2mat(name(9)));
                    name_list(i) = name;
                end
                [~,index] = sort(name_list);
                app.MD_dicom_name_list_high = file_list(index);
            else
                app.MD_dicom_name_list_high = file_list;
            end
            % 更改图标
            app.MD_slice_num = length(file_list);
            app.SliceSlider_2.Limits = [1 app.MD_slice_num];
            MD_read_dicom(app,[0 1]);
            show_MD_dicom(app,[0 1 0 0]);
        end

        % Value changed function: EditField_7
        function EditField_7ValueChanged(app, event)
            app.MD_WW_WL_HL(1) = app.EditField_7.Value;
            show_MD_dicom(app,[1 1 0 0]);
        end

        % Value changed function: EditField_8
        function EditField_8ValueChanged(app, event)
            app.MD_WW_WL_HL(2) = app.EditField_8.Value;
            show_MD_dicom(app,[1 1 0 0]);
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            app.MD_WW_WL_M1(1) = app.EditField_3.Value;
            show_MD_dicom(app,[0 0 1 0]);
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            app.MD_WW_WL_M1(2) = app.EditField_4.Value;
            show_MD_dicom(app,[0 0 1 0]);
        end

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
            app.MD_WW_WL_M2(1) = app.EditField_5.Value;
            show_MD_dicom(app,[0 0 0 1]);
        end

        % Value changed function: EditField_6
        function EditField_6ValueChanged(app, event)
            app.MD_WW_WL_M2(2) = app.EditField_6.Value;
            show_MD_dicom(app,[0 0 0 1]);
        end

        % Value changed function: SliceSlider_2
        function SliceSlider_2ValueChanged(app, event)
            value = app.SliceSlider_2.Value;
            app.MD_slice_show_index = fix(value);
            MD_read_dicom(app,[1 1]);
            show_MD_dicom(app,[1 1 0 0]);
        end

        % Button pushed function: addMDtable
        function addMDtablePushed(app, event)
            if ~isdir(app.MD_baseMaterial_table_path)
                [temp_file,temp_path] = uigetfile('.mat','选择物质分解校正表',app.MD_baseMaterial_table_path);  % 创建标准的对话框并通过交互式操作取得文件夹名
            else
                [temp_file,temp_path] = uigetfile('.mat','选择物质分解校正表',fileparts(app.MD_baseMaterial_table_path));  % 创建标准的对话框并通过交互式操作取得文件夹名
            end
            if temp_path==0
                return
            else
                app.MD_baseMaterial_table_path = fullfile(temp_path, temp_file);
            end
            temp_table = load(app.MD_baseMaterial_table_path);
            is_new_item = ~ismember(temp_table.cali_M(1),app.MD_baseMaterial);
            if is_new_item
                app.MD_baseMaterial = [app.MD_baseMaterial;temp_table.cali_M(1)];
                app.MD_baseMaterial_table = [app.MD_baseMaterial_table;temp_table.cali_data(1,1:2)];
            end
            app.UITable_MD.Data = table(app.MD_baseMaterial,app.MD_baseMaterial_table(:,1),app.MD_baseMaterial_table(:,2));
            app.DropDown.Items = app.MD_baseMaterial;
            app.DropDown_2.Items = app.MD_baseMaterial;
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            [~, idx] = ismember(value, app.MD_baseMaterial);
            app.MD_choose_M1 = idx;
            app.MD_choose_table(1:2,1) = app.MD_baseMaterial_table(idx,:);
            app.MD_run_index = 1;
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            value = app.DropDown_2.Value;
            [~, idx] = ismember(value, app.MD_baseMaterial);
            app.MD_choose_M2 = idx;
            app.MD_choose_table(1:2,2) = app.MD_baseMaterial_table(idx,:);
            app.MD_run_index = 1;
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            if (isempty(app.MD_image_low))||(isempty(app.MD_image_high))
                errordlg('请加载高低能数据！', '错误');
                return
            elseif app.MD_choose_M1 == app.MD_choose_M2
                errordlg('请选择两种不同的基物质进行分解！', '错误');
                return
            else
                temp_low = app.MD_image_low + 1000;
                temp_high = app.MD_image_high + 1000;
                img_size = size(temp_low);
                img = [temp_low(:)';temp_high(:)'];
                MD = app.MD_choose_table;
                temp_DM_images = MD\img * 1000;% g/cm^3 to mg/cm^3
                app.MD_image_M1 = reshape(temp_DM_images(1,:),img_size);
                app.MD_image_M2 = reshape(temp_DM_images(2,:),img_size);
                show_MD_dicom(app,[0 0 1 1]);
            end
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            if ~isdir(app.MD_save_path)
                temp_dir = uigetdir(fileparts(app.MD_dicom_dir_high),'选择分解图像保持根路径');  % 创建标准的对话框并通过交互式操作取得文件夹名
            else
                temp_dir = uigetdir(app.MD_save_path,'选择分解图像保持根路径');  % 创建标准的对话框并通过交互式操作取得文件夹名
            end
            if temp_dir~=0
                app.MD_save_path = temp_dir;
            else
                return
            end
            app.MD_show_log.Value = app.MD_save_path;
        end

        % Value changed function: Button_8
        function Button_8ValueChanged(app, event)
            value = app.Button_8.Value;
            if ~isdir(app.MD_save_path)
                errordlg('请先选择保存路径！', '错误');
                app.Button_8.Value = 0;
                return
            end
            if value
                app.MD_run_enable = 1;
                MD_image_and_save(app)
                app.Button_8.Value = 0;
            else
                app.MD_run_enable = 0;
            end
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            value = min(app.Spinner.Value,app.Spinner_2.Value);
            app.Spinner.Value = value;
            app.cali_slice_show_index = value;
            read_dicom(app);
            show_cali_dicom(app);
            app.cali_is_first_slice = 1;
            show_circle(app);
            app.cali_slice_index(1) = value;
            app.SliceSlider.Value(1) = value;
        end

        % Value changed function: Spinner_2
        function Spinner_2ValueChanged(app, event)
            value = max(app.Spinner.Value,min(app.Spinner_2.Value,app.cali_slice_num));
            app.Spinner_2.Value = value;
            app.cali_slice_show_index = value;
            read_dicom(app);
            show_cali_dicom(app);
            app.cali_is_first_slice = 0;
            show_circle(app);
            app.cali_slice_index(2) = value;
            app.SliceSlider.Value(2) = value;
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            value = app.Spinner.Value;
            app.cali_slice_show_index = app.cali_slice_index(1);
            read_dicom(app);
            show_cali_dicom(app);
            app.cali_is_first_slice = 1;
            show_circle(app);
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            value = app.Spinner_2.Value;
            app.cali_slice_show_index = app.cali_slice_index(2);
            read_dicom(app);
            show_cali_dicom(app);
            app.cali_is_first_slice = 0;
            show_circle(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab_4 = uitab(app.TabGroup);
            app.Tab_4.Title = '物质标定';
            app.UIAxes = uiaxes(app.Tab_4);
            title(app.UIAxes, '标定结果')
            xlabel(app.UIAxes, '基物质密度(mg/cm^3)')
            ylabel(app.UIAxes, 'CT值(HU)')
            zlabel(app.UIAxes, 'Z')
            app.CaliShow = uiaxes(app.Tab_4);
            title(app.CaliShow, 'CT Image')
            app.CaliShow.XTick = [];
            app.CaliShow.YTick = [];
            app.CaliShow.ButtonDownFcn = createCallbackFcn(app, @CaliShowButtonDown, true);
            colormap(app.CaliShow, 'gray')
            app.UITable = uitable(app.Tab_4);
            app.UITable.ColumnName = {'基物质浓度(mg/cm^3)'; '背景物质浓度(mg/cm^3)'; 'Low-CT值(HU)'; 'High-CT值(HU)'; 'Total-CT值(HU)'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.LoadDicomFilesButton = uibutton(app.Tab_4, 'push');
            app.LoadDicomFilesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDicomFilesButtonPushed, true);
            app.LoadDicomFilesButton.Text = 'Load Dicom Files';
            app.Button = uibutton(app.Tab_4, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '产表';
            app.Button_2 = uibutton(app.Tab_4, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '添加+';
            app.Button_3 = uibutton(app.Tab_4, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '保存';
            app.water1000Label_2 = uilabel(app.Tab_4);
            app.water1000Label_2.Text = {'基物质质量衰减'; '(μ/μ_water*1000)'};
            app.SliceSliderLabel = uilabel(app.Tab_4);
            app.SliceSliderLabel.Text = 'Slice';
            app.SliceSlider = uislider(app.Tab_4, 'range');
            app.SliceSlider.Limits = [1 100];
            app.SliceSlider.ValueChangedFcn = createCallbackFcn(app, @SliceSliderValueChanged, true);
            app.SliceSlider.Step = 1;
            app.SliceSlider.Tag = 'min,max';
            app.SliceSlider.Value = [1 100];
            app.Label_2 = uilabel(app.Tab_4);
            app.Label_2.Text = '基物质';
            app.TextArea = uitextarea(app.Tab_4);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.LowLampLabel = uilabel(app.Tab_4);
            app.LowLampLabel.Text = 'Low';
            app.LowLamp = uilamp(app.Tab_4);
            app.HighLampLabel = uilabel(app.Tab_4);
            app.HighLampLabel.Text = 'High';
            app.HighLamp = uilamp(app.Tab_4);
            app.TotalLampLabel = uilabel(app.Tab_4);
            app.TotalLampLabel.Text = 'Total';
            app.TotalLamp = uilamp(app.Tab_4);
            app.Label_3 = uilabel(app.Tab_4);
            app.Label_3.Text = '背景物质';
            app.TextArea_2 = uitextarea(app.Tab_4);
            app.TextArea_2.ValueChangedFcn = createCallbackFcn(app, @TextArea_2ValueChanged, true);
            app.LowTextAreaLabel = uilabel(app.Tab_4);
            app.LowTextAreaLabel.Text = 'Low';
            app.LowTextArea = uitextarea(app.Tab_4);
            app.LowTextArea.Editable = 'off';
            app.Label_11 = uilabel(app.Tab_4);
            app.Label_11.Text = '窗宽';
            app.EditField = uieditfield(app.Tab_4, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 1000;
            app.Label_12 = uilabel(app.Tab_4);
            app.Label_12.Text = '窗位';
            app.EditField_2 = uieditfield(app.Tab_4, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Value = 400;
            app.Label_13 = uilabel(app.Tab_4);
            app.Label_13.Text = 'Mask半径';
            app.MaskEditField = uieditfield(app.Tab_4, 'numeric');
            app.MaskEditField.ValueChangedFcn = createCallbackFcn(app, @MaskEditFieldValueChanged, true);
            app.MaskEditField.Value = 100;
            app.mgcm3EditFieldLabel = uilabel(app.Tab_4);
            app.mgcm3EditFieldLabel.Text = {'基物质密度'; '(mg/cm^3)'};
            app.mgcm3EditField = uieditfield(app.Tab_4, 'numeric');
            app.mgcm3EditField.ValueChangedFcn = createCallbackFcn(app, @mgcm3EditFieldValueChanged, true);
            app.mgcm3EditField.Value = 1000;
            app.mgcm3Label_2 = uilabel(app.Tab_4);
            app.mgcm3Label_2.Text = {'背景物质密度'; '(mg/cm^3)'};
            app.mgcm3EditField_2 = uieditfield(app.Tab_4, 'numeric');
            app.mgcm3EditField_2.ValueChangedFcn = createCallbackFcn(app, @mgcm3EditField_2ValueChanged, true);
            app.mgcm3EditField_2.Value = 1000;
            app.HighTextAreaLabel = uilabel(app.Tab_4);
            app.HighTextAreaLabel.Text = 'High';
            app.HighTextArea = uitextarea(app.Tab_4);
            app.HighTextArea.Editable = 'off';
            app.TotalTextAreaLabel = uilabel(app.Tab_4);
            app.TotalTextAreaLabel.Text = 'Total';
            app.TotalTextArea = uitextarea(app.Tab_4);
            app.TotalTextArea.Editable = 'off';
            app.water1000Label_3 = uilabel(app.Tab_4);
            app.water1000Label_3.Text = '背景物质质量衰减(μ/μ_water*1000)';
            app.LowTextArea_4Label = uilabel(app.Tab_4);
            app.LowTextArea_4Label.Text = 'Low';
            app.LowTextArea_4 = uitextarea(app.Tab_4);
            app.LowTextArea_4.Editable = 'off';
            app.HighTextArea_2Label = uilabel(app.Tab_4);
            app.HighTextArea_2Label.Text = 'High';
            app.HighTextArea_2 = uitextarea(app.Tab_4);
            app.HighTextArea_2.Editable = 'off';
            app.TotalTextArea_2Label = uilabel(app.Tab_4);
            app.TotalTextArea_2Label.Text = 'Total';
            app.TotalTextArea_2 = uitextarea(app.Tab_4);
            app.TotalTextArea_2.Editable = 'off';
            app.Button_9 = uibutton(app.Tab_4, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '删除-';
            app.MaskEditField_2Label = uilabel(app.Tab_4);
            app.MaskEditField_2Label.Text = 'Mask半径';
            app.MaskEditField_2 = uieditfield(app.Tab_4, 'numeric');
            app.MaskEditField_2.ValueChangedFcn = createCallbackFcn(app, @MaskEditField_2ValueChanged, true);
            app.MaskEditField_2.Value = 100;
            app.Button_10 = uibutton(app.Tab_4, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '起始';
            app.Button_11 = uibutton(app.Tab_4, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '终止';
            app.Spinner = uispinner(app.Tab_4);
            app.Spinner.Limits = [1 Inf];
            app.Spinner.ValueDisplayFormat = '%.0f';
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.Spinner.Value = 1;
            app.Spinner_2 = uispinner(app.Tab_4);
            app.Spinner_2.Limits = [1 Inf];
            app.Spinner_2.ValueDisplayFormat = '%.0f';
            app.Spinner_2.ValueChangedFcn = createCallbackFcn(app, @Spinner_2ValueChanged, true);
            app.Spinner_2.Value = 100;
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '物质分解';
            app.MDShow_1 = uiaxes(app.Tab_2);
            title(app.MDShow_1, ' Low Energy Image')
            app.MDShow_1.XTick = [];
            app.MDShow_1.YTick = [];
            colormap(app.MDShow_1, 'gray')
            app.MDShow_2 = uiaxes(app.Tab_2);
            title(app.MDShow_2, 'High Energy Image')
            app.MDShow_2.XTick = [];
            app.MDShow_2.YTick = [];
            colormap(app.MDShow_2, 'gray')
            app.MDShow_3 = uiaxes(app.Tab_2);
            title(app.MDShow_3, 'Base Material 1')
            app.MDShow_3.XTick = [];
            app.MDShow_3.YTick = [];
            colormap(app.MDShow_3, 'gray')
            app.MDShow_4 = uiaxes(app.Tab_2);
            title(app.MDShow_4, 'Base Material 2')
            app.MDShow_4.XTick = [];
            app.MDShow_4.YTick = [];
            colormap(app.MDShow_4, 'gray')
            app.LowDicomFilesButton = uibutton(app.Tab_2, 'push');
            app.LowDicomFilesButton.ButtonPushedFcn = createCallbackFcn(app, @LowDicomFilesButtonPushed, true);
            app.LowDicomFilesButton.Text = 'Low Dicom Files';
            app.Label = uilabel(app.Tab_2);
            app.Label.Text = 'Slice';
            app.SliceSlider_2 = uislider(app.Tab_2);
            app.SliceSlider_2.Limits = [1 100];
            app.SliceSlider_2.ValueChangedFcn = createCallbackFcn(app, @SliceSlider_2ValueChanged, true);
            app.SliceSlider_2.Value = 1;
            app.HighDicomFilesButton = uibutton(app.Tab_2, 'push');
            app.HighDicomFilesButton.ButtonPushedFcn = createCallbackFcn(app, @HighDicomFilesButtonPushed, true);
            app.HighDicomFilesButton.Text = 'High Dicom Files';
            app.UITable_MD = uitable(app.Tab_2);
            app.UITable_MD.ColumnName = {'基物质'; '低能'; '高能'};
            app.UITable_MD.RowName = {};
            app.UITable_MD.ColumnEditable = true;
            app.addMDtable = uibutton(app.Tab_2, 'push');
            app.addMDtable.ButtonPushedFcn = createCallbackFcn(app, @addMDtablePushed, true);
            app.addMDtable.Text = '加载分解校正表+';
            app.Button_5 = uibutton(app.Tab_2, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '预分解';
            app.Label_10 = uilabel(app.Tab_2);
            app.Label_10.Text = '保存路径';
            app.MD_show_log = uitextarea(app.Tab_2);
            app.Button_6 = uibutton(app.Tab_2, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '批量分解保存路径';
            app.Button_8 = uibutton(app.Tab_2, 'state');
            app.Button_8.ValueChangedFcn = createCallbackFcn(app, @Button_8ValueChanged, true);
            app.Button_8.Text = '开始/停止';
            app.EditField_3Label = uilabel(app.Tab_2);
            app.EditField_3Label.Text = '窗宽';
            app.EditField_3 = uieditfield(app.Tab_2, 'numeric');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_3.Value = 1000;
            app.EditField_4Label = uilabel(app.Tab_2);
            app.EditField_4Label.Text = '窗位';
            app.EditField_4 = uieditfield(app.Tab_2, 'numeric');
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.EditField_4.Value = 400;
            app.EditField_5Label = uilabel(app.Tab_2);
            app.EditField_5Label.Text = '窗宽';
            app.EditField_5 = uieditfield(app.Tab_2, 'numeric');
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            app.EditField_5.Value = 1000;
            app.EditField_6Label = uilabel(app.Tab_2);
            app.EditField_6Label.Text = '窗位';
            app.EditField_6 = uieditfield(app.Tab_2, 'numeric');
            app.EditField_6.ValueChangedFcn = createCallbackFcn(app, @EditField_6ValueChanged, true);
            app.EditField_6.Value = 400;
            app.EditField_7Label = uilabel(app.Tab_2);
            app.EditField_7Label.Text = '窗宽';
            app.EditField_7 = uieditfield(app.Tab_2, 'numeric');
            app.EditField_7.ValueChangedFcn = createCallbackFcn(app, @EditField_7ValueChanged, true);
            app.EditField_7.Value = 1000;
            app.EditField_8Label = uilabel(app.Tab_2);
            app.EditField_8Label.Text = '窗位';
            app.EditField_8 = uieditfield(app.Tab_2, 'numeric');
            app.EditField_8.ValueChangedFcn = createCallbackFcn(app, @EditField_8ValueChanged, true);
            app.EditField_8.Value = 400;
            app.Label_14 = uilabel(app.Tab_2);
            app.Label_14.Text = '基物质1';
            app.DropDown = uidropdown(app.Tab_2);
            app.DropDown.Items = {'Water'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Water';
            app.Label_15 = uilabel(app.Tab_2);
            app.Label_15.Text = '基物质2';
            app.DropDown_2 = uidropdown(app.Tab_2);
            app.DropDown_2.Items = {'Water'};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Value = 'Water';
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '虚拟单能图';
            app.TODOLabel = uilabel(app.Tab_3);
            app.TODOLabel.Text = 'TODO...';
            app.Tab_5 = uitab(app.TabGroup);
            app.Tab_5.Title = '去噪';
            app.TODOLabel_2 = uilabel(app.Tab_5);
            app.TODOLabel_2.Text = 'TODO...';
            app.Tab_7 = uitab(app.TabGroup);
            app.Tab_7.Title = '校正';
            app.TODOLabel_3 = uilabel(app.Tab_7);
            app.TODOLabel_3.Text = 'TODO...';
            app.Tab_6 = uitab(app.TabGroup);
            app.Tab_6.Title = '重建';
            app.TODOLabel_4 = uilabel(app.Tab_6);
            app.TODOLabel_4.Text = 'TODO...';
            app.PCCTToolkitV01zhouenzeLabel = uilabel(app.UIFigure);
            app.PCCTToolkitV01zhouenzeLabel.Text = 'PCCT Toolkit—V0.1 zhouenze';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PcctTool

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end